classdef object_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        PanelLayout                     matlab.ui.container.GridLayout
        ShapeDetectionDropDown          matlab.ui.control.DropDown
        ShapeDetectionLabel             matlab.ui.control.Label
        InputTextDescriptionLabel       matlab.ui.control.Label
        EdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        EdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        InputTextEditFieldLabel         matlab.ui.control.Label
        InputTextEditField              matlab.ui.control.EditField
        InputNum2EditFieldLabel         matlab.ui.control.Label
        InputNum1EditFieldLabel         matlab.ui.control.Label
        InputNum1EditField              matlab.ui.control.NumericEditField
        InputNum2EditField              matlab.ui.control.NumericEditField
        ExecuteButton                   matlab.ui.control.Button
        SelectedImageLabel              matlab.ui.control.Label
        BrowseImageLabel                matlab.ui.control.Label
        BrowseImageButton               matlab.ui.control.Button
        TitleLabel                      matlab.ui.control.Label
        AlgoTitleLabel                  matlab.ui.control.Label
        ImageGrid                       matlab.ui.container.GridLayout
        ParameterDomainGrid             matlab.ui.container.GridLayout
        ParameterDomainImage            matlab.ui.control.Image
        ParameterDomainLabel            matlab.ui.control.Label
        ObjectDetectionGrid             matlab.ui.container.GridLayout
        ObjectDetectionImage            matlab.ui.control.Image
        ObjectDetectionLabel            matlab.ui.control.Label
        EdgeDetectionGrid               matlab.ui.container.GridLayout
        EdgeDetectionImage              matlab.ui.control.Image
        EdgeDetectionLabel              matlab.ui.control.Label
        InputImageGrid                  matlab.ui.container.GridLayout
        InputImage                      matlab.ui.control.Image
        InputImageLabel                 matlab.ui.control.Label
    end

    properties (Access = public)
        INPUT_IMAGE = [];
        EDGE_DETECTED_IMAGE = [];
        OBJECT_DETECTED_IMAGE = [];
        LOG_STR = "";
    end
    
    properties (Access = private)

    end


    methods (Access = public)
        function display_image_on_ui(~, img, ui)
            if (size(img, 3) == 1)
                grayImage = img;
                image_to_display = repmat(im2double(grayImage), [1, 1, 3]);
            else
                image_to_display = img;
            end
            ui.ImageSource = image_to_display;
        end

        function set_panel_config(app, config)
            if (ismember(1, config))
                app.InputNum1EditField.Visible = "on";
                app.InputNum1EditFieldLabel.Visible = "on";
            else
                app.InputNum1EditField.Visible = "off";
                app.InputNum1EditFieldLabel.Visible = "off";
            end

            if (ismember(2, config))
                app.InputNum2EditField.Visible = "on";
                app.InputNum2EditFieldLabel.Visible = "on";
            else
                app.InputNum2EditField.Visible = "off";
                app.InputNum2EditFieldLabel.Visible = "off";
            end

            if (ismember(3, config))
                app.InputTextEditField.Visible = "on";
                app.InputTextEditFieldLabel.Visible = "on";
                app.InputTextDescriptionLabel.Visible = "on";
            else
                app.InputTextEditField.Visible = "off";
                app.InputTextEditFieldLabel.Visible = "off";
                app.InputTextDescriptionLabel.Visible = "off";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.SelectedImageLabel.Text = fileName;
                app.INPUT_IMAGE= imread(fullFileName);
                
                display_image_on_ui(app, app.INPUT_IMAGE, app.InputImage);
            end
        end

        % Value changed function: EdgeDetectionAlgorithmDropDown
        function EdgeDetectionAlgorithmDropDownValueChanged(app, event)
            value = app.EdgeDetectionAlgorithmDropDown.Value;
            if (app.ShapeDetectionDropDown.Value == "None")
                app.AlgoTitleLabel.Text = strcat(value, " Operator");
            else % app.ShapeDetectionDropDown.Value == "Line" || "Circle"
                app.AlgoTitleLabel.Text = strcat(value, " Operator and Hough Transformation");
            end
                
            if (value == "Laplace")
                app.set_panel_config([1, 3]);
                app.InputNum1EditFieldLabel.Text = "NxN Kernel";
                app.InputNum1EditField.Value = 3;
                app.InputTextEditFieldLabel.Text = "Kernel String";
                % app.InputTextEditField.Value = "";
            elseif (value == "LoG")
                app.set_panel_config([1, 2, 3]);
                app.InputNum1EditFieldLabel.Text = "NxN Kernel"; 
                app.InputNum2EditFieldLabel.Text = "Sigma";
                app.InputNum1EditField.Value = 3;
                app.InputNum2EditField.Value = 0.4;
                app.InputTextEditFieldLabel.Text = "Kernel String";
                % app.InputTextEditField.Value = "";
            elseif (value == "Sobel")
                app.set_panel_config([1]);
                app.InputNum1EditFieldLabel.Text = "Multiplier Scale";
                app.InputNum1EditField.Value = 1;
            elseif(value == "Prewitt")
                app.set_panel_config([1]);
                app.InputNum1EditFieldLabel.Text = "Multiplier Scale";
                app.InputNum1EditField.Value = 1;
            elseif (value == "Roberts")
                app.set_panel_config([1]);
                app.InputNum1EditFieldLabel.Text = "Multiplier Scale";
                app.InputNum1EditField.Value = 1;
            else
                app.set_panel_config([]);
            end
            
        end

        % Value changed function: ShapeDetectionDropDown
        function ShapeDetectionDropDownValueChanged(app, event)
            value = app.ShapeDetectionDropDown.Value;
            if value == "Line"
                app.TitleLabel.Text = "Line Detection";
                app.ObjectDetectionLabel.Text = "Line Detected Image";
                app.ParameterDomainImage.Visible = "on";
                app.ParameterDomainLabel.Visible = "on";
                app.AlgoTitleLabel.Text = strcat(app.EdgeDetectionAlgorithmDropDown.Value, " Operator and Hough Transformation");
            elseif value == "Circle"
                app.TitleLabel.Text = "Circle Detection";
                app.ObjectDetectionLabel.Text = "Circle Detected Image";
                app.ParameterDomainImage.Visible = "on";
                app.ParameterDomainLabel.Visible = "on";
                app.AlgoTitleLabel.Text = strcat(app.EdgeDetectionAlgorithmDropDown.Value, " Operator and Hough Transformation");
            else % value == "None"
                app.TitleLabel.Text = "Object Segmentation";
                app.ObjectDetectionLabel.Text = "Object Segmented Image";
                app.ParameterDomainImage.Visible = "off";
                app.ParameterDomainLabel.Visible = "off";
                app.AlgoTitleLabel.Text = strcat(app.EdgeDetectionAlgorithmDropDown.Value, " Operator");
            end

            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("src\edge_detection\");
            addpath("src\matrix\");
            algo = app.EdgeDetectionAlgorithmDropDown.Value;
   

            if (algo == "Laplace")
                app.EDGE_DETECTED_IMAGE = laplacian_edge_detection(app.INPUT_IMAGE, app.InputNum1EditField.Value, app.InputTextEditField.Value);
            elseif(algo == "LoG")
                app.EDGE_DETECTED_IMAGE = laplacian_of_gaussian_edge_detection(app.INPUT_IMAGE, app.InputNum1EditField.Value, app.InputNum2EditField.Value, app.InputTextEditField.Value);
            elseif(algo == "Sobel" || algo == "Prewitt" || algo == "Roberts")
                app.EDGE_DETECTED_IMAGE = sobel_prewitt_roberts_edge_detection(app.INPUT_IMAGE, algo, app.InputNum1EditField.Value);
            else % value == "Canny
                app.EDGE_DETECTED_IMAGE = canny_edge_detection(app.INPUT_IMAGE);
            end

            app.display_image_on_ui(app.EDGE_DETECTED_IMAGE, app.EdgeDetectionImage);

            if (app.ShapeDetectionDropDown.Value == "Line")
                % TODO
            elseif (app.ShapeDetectionDropDown.Value == "Circle")
                % TODO
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageGrid = uigridlayout(app.GridLayout);
            app.InputImageGrid = uigridlayout(app.ImageGrid);
            app.InputImageLabel = uilabel(app.InputImageGrid);
            app.InputImageLabel.Text = 'Input Image';
            app.InputImage = uiimage(app.InputImageGrid);
            app.EdgeDetectionGrid = uigridlayout(app.ImageGrid);
            app.EdgeDetectionLabel = uilabel(app.EdgeDetectionGrid);
            app.EdgeDetectionLabel.Text = 'Edge Detected Image';
            app.EdgeDetectionImage = uiimage(app.EdgeDetectionGrid);
            app.ObjectDetectionGrid = uigridlayout(app.ImageGrid);
            app.ObjectDetectionLabel = uilabel(app.ObjectDetectionGrid);
            app.ObjectDetectionLabel.Text = 'Object Segmented Image';
            app.ObjectDetectionImage = uiimage(app.ObjectDetectionGrid);
            app.ParameterDomainGrid = uigridlayout(app.ImageGrid);
            app.ParameterDomainLabel = uilabel(app.ParameterDomainGrid);
            app.ParameterDomainLabel.Visible = 'off';
            app.ParameterDomainLabel.Text = 'Parameter Domain Image';
            app.ParameterDomainImage = uiimage(app.ParameterDomainGrid);
            app.ParameterDomainImage.Visible = 'off';
            app.PanelLayout = uigridlayout(app.GridLayout);
            app.AlgoTitleLabel = uilabel(app.PanelLayout);
            app.AlgoTitleLabel.Text = 'Laplace Operator';
            app.TitleLabel = uilabel(app.PanelLayout);
            app.TitleLabel.Text = 'Object Segmentation';
            app.BrowseImageButton = uibutton(app.PanelLayout, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse...';
            app.BrowseImageLabel = uilabel(app.PanelLayout);
            app.BrowseImageLabel.Text = 'Input Image';
            app.SelectedImageLabel = uilabel(app.PanelLayout);
            app.SelectedImageLabel.Text = 'No image has been chosen';
            app.ExecuteButton = uibutton(app.PanelLayout, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.InputNum2EditField = uieditfield(app.PanelLayout, 'numeric');
            app.InputNum2EditField.Visible = 'off';
            app.InputNum2EditField.Value = 1;
            app.InputNum1EditField = uieditfield(app.PanelLayout, 'numeric');
            app.InputNum1EditField.Value = 3;
            app.InputNum1EditFieldLabel = uilabel(app.PanelLayout);
            app.InputNum1EditFieldLabel.Text = 'NxN Kernel';
            app.InputNum2EditFieldLabel = uilabel(app.PanelLayout);
            app.InputNum2EditFieldLabel.Visible = 'off';
            app.InputNum2EditFieldLabel.Text = 'InputNum2';
            app.InputTextEditField = uieditfield(app.PanelLayout, 'text');
            app.InputTextEditFieldLabel = uilabel(app.PanelLayout);
            app.InputTextEditFieldLabel.Text = 'Kernel String';
            app.EdgeDetectionAlgorithmDropDownLabel = uilabel(app.PanelLayout);
            app.EdgeDetectionAlgorithmDropDownLabel.Text = 'Edge Detection Algorithm';
            app.EdgeDetectionAlgorithmDropDown = uidropdown(app.PanelLayout);
            app.EdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionAlgorithmDropDownValueChanged, true);
            app.EdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            app.InputTextDescriptionLabel = uilabel(app.PanelLayout);
            app.InputTextDescriptionLabel.Text = 'Input Kernel 3x3 Example: 0,1,0;1,-4,1;0,1,0';
            app.ShapeDetectionLabel = uilabel(app.PanelLayout);
            app.ShapeDetectionLabel.Text = 'Shape Detection';
            app.ShapeDetectionDropDown = uidropdown(app.PanelLayout);
            app.ShapeDetectionDropDown.Items = {'None', 'Line', 'Circle'};
            app.ShapeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDetectionDropDownValueChanged, true);
            app.ShapeDetectionDropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = object_segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end